classdef menghitungobjek < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DeteksiButton    matlab.ui.control.Button
        BukaCitraButton  matlab.ui.control.Button
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BukaCitraButton
        function BukaCitraButtonPushed(app, event)
            
            [nama_file, nama_folder] = uigetfile('*.jpg');
            drawnow
            figure(app.UIFigure)

            if ~isequal(nama_file,0)
                Img = imread(fullfile(nama_folder,nama_file));

                imshow(Img,'Parent',app.UIAxes)
                title(app.UIAxes,'Citra Asli')

                app.Property.Img = Img;
            end
            
        end

        % Button pushed function: DeteksiButton
        function DeteksiButtonPushed(app, event)
            
            Img = app.Property.Img;

            Img_gray = rgb2gray(Img);
            bw = Img_gray<240;
            bw = bwareaopen(bw,1000);

            [labeled,numObjects] = bwlabel(bw);
            stast = regionprops(labeled,'Boundingbox');
            bbox = cat(1,stats.BoundingBox);

            Img_obj = insertShape(Img,"Rectangle",bbox,"LineWidth",10)

            imshow(Img_obj,"Parent",app.UIAxes_2)
            title(app.UIAxes_2,['Jumlah Objek = ',num2str(numObjects)])

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.BukaCitraButton = uibutton(app.UIFigure, 'push');
            app.BukaCitraButton.ButtonPushedFcn = createCallbackFcn(app, @BukaCitraButtonPushed, true);
            app.BukaCitraButton.Text = 'Buka Citra';
            app.DeteksiButton = uibutton(app.UIFigure, 'push');
            app.DeteksiButton.ButtonPushedFcn = createCallbackFcn(app, @DeteksiButtonPushed, true);
            app.DeteksiButton.Text = 'Deteksi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menghitungobjek

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end